classdef MEP_viewer2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        UseDataRCheckBox             matlab.ui.control.CheckBox
        SelectendpointRButton        matlab.ui.control.Button
        SelectlatencyonsetRButton    matlab.ui.control.Button
        ResetlatencyendpointRButton  matlab.ui.control.Button
        ShowEMGrightCheckBox         matlab.ui.control.CheckBox
        ShowEMGleftCheckBox          matlab.ui.control.CheckBox
        channelSelector              matlab.ui.control.DropDown
        EMGrightDropDown             matlab.ui.control.DropDown
        EMGrightDropDownLabel        matlab.ui.control.Label
        EMGleftDropDown              matlab.ui.control.DropDown
        EMGleftDropDownLabel         matlab.ui.control.Label
        IOcurveOKCheckBox            matlab.ui.control.CheckBox
        IsMEPCheckBox                matlab.ui.control.CheckBox
        ResetlatencyendpointLButton  matlab.ui.control.Button
        lambdaEditField              matlab.ui.control.NumericEditField
        lambdaLabel                  matlab.ui.control.Label
        BaselineendEditField         matlab.ui.control.NumericEditField
        BaselineendEditFieldLabel    matlab.ui.control.Label
        BaselinestartEditField       matlab.ui.control.NumericEditField
        BaselinestartEditFieldLabel  matlab.ui.control.Label
        SelectendpointLButton        matlab.ui.control.Button
        SelectlatencyonsetLButton    matlab.ui.control.Button
        OpeninnewwindowButton        matlab.ui.control.Button
        ExportallsubjectsButton      matlab.ui.control.Button
        repetitionSelector           matlab.ui.control.DropDown
        ZoominCheckBox               matlab.ui.control.CheckBox
        BlockbasedmeasuresLabel      matlab.ui.control.Label
        TrialbasedmeasuresLabel      matlab.ui.control.Label
        BlockMEPTable                matlab.ui.control.Table
        ExportsubjectButton          matlab.ui.control.Button
        SingleMEPTable               matlab.ui.control.Table
        UseDataLCheckBox             matlab.ui.control.CheckBox
        trialsTable                  matlab.ui.control.Table
        MEPSelector                  matlab.ui.control.DropDown
        Panel                        matlab.ui.container.Panel
        quantitySelector             matlab.ui.control.DropDown
        subjectSelector              matlab.ui.control.DropDown
    end

    
    properties (Access = private)
        subject % subject name
        repetition % repetition (within the subject folder)
        data % Data for the current subject
        toUseTable % whether to use a trial
        IOcurve_params % IOcurve parameters
        channelDescriptions % which is channel left and right EMG
    end
    
    methods (Access = private)
        function updateSubject(app)
            thisSubject = app.subjectSelector.Value;
            app.subject = thisSubject;

            filenames = getRepetitions(app.subject);
            app.repetitionSelector.Items = filenames;
            updateRepetition(app);
        end

        function fn = getToUseLocation(app)
            fn = ['results/' app.subject '/' app.repetition '_touse.csv'];
        end

        function fn = getBlockDescriptionsLocation(app)
            fn = ['results/' app.subject '/' app.repetition '.csv'];
        end

        function fn = getChannelDescriptionLocation(app)
            fn = ['results/' app.subject '/' app.repetition '_channelDescription.csv'];
        end

        function fn = getIOCurve_paramLocation(app)
            fn = ['results/' app.subject '/' app.repetition '_IOcurve_params.csv'];
        end


        function [channel,channels] = getChannels(app)
            channels(1) = dropdown_index(app.EMGleftDropDown);
            channels(2) = dropdown_index(app.EMGrightDropDown);
            if strcmp(app.channelSelector.Value,'EMG left')
                channel = channels(1);
            elseif strcmp(app.channelSelector.Value,'EMG right')
                channel = channels(2);
            else
                error('Something is wrong');
            end
        end

        function updateRepetition(app)
            thisSubject = app.subjectSelector.Value;
            thisRepetition = app.repetitionSelector.Value;
            app.subject = thisSubject;
            app.repetition= thisRepetition;
            app.data=loaddata(['results/' app.subject '/' app.repetition]);
            if ~isstruct(app.data) && isnan(app.data)
                return
            end
            % Update the table
            fn_blockdescriptions = getBlockDescriptionsLocation(app);
            if exist(fn_blockdescriptions,'file')
                t = readtable(fn_blockdescriptions);
            else
                t = app.data.blockdescriptions;
            end
            app.trialsTable.Data = t;
            
            toUseFilename = getToUseLocation(app);
            app.IOcurveOKCheckBox.Visible = 'off';
            if exist(toUseFilename,'file')
                app.toUseTable = readtable(toUseFilename);
            else
                app.toUseTable = table;
                N = size(app.data.e,3);
                app.toUseTable.trial = (1:N)';
                app.toUseTable.toUse_1 = ones(N,1);
                app.toUseTable.toUse_2 = ones(N,1);
                app.toUseTable.latencyOnset_1 = -1 * ones(N,1); %-1 indicates has not been set (yet)
                app.toUseTable.latencyOnset_2 = -1 * ones(N,1); %-1 indicates has not been set (yet)
                app.toUseTable.endpoint_1 = -1 * ones(N,1);
                app.toUseTable.endpoint_2 = -1 * ones(N,1);
                app.toUseTable.isMEP = ones(N,1);
                params = globalparameters;
                app.toUseTable.baselineStart = ((params.baseline_start-1000)./app.data.samplerate) * 1000 * ones(N,1);
                app.toUseTable.baselineEnd = params.baseline_length * ones(N,1)/2 + app.toUseTable.baselineStart;
                app.toUseTable.lambda = 3 * ones(N,1);
            end

            app.EMGleftDropDown.Items = string(app.data.channelnames);
            app.EMGrightDropDown.Items = string(app.data.channelnames);

            channelDescriptionFilename = getChannelDescriptionLocation(app);
            if exist(channelDescriptionFilename,'file')
                app.channelDescriptions = readtable(channelDescriptionFilename);
                app.EMGleftDropDown.Value = app.channelDescriptions.names{1};
                app.EMGrightDropDown.Value = app.channelDescriptions.names{2};
            else
                app.channelDescriptions = table;
                app.channelDescriptions.channels = [1;2];
                app.channelDescriptions.names = app.data.channelnames(1:2,:);
                app.EMGleftDropDown.Value = app.EMGleftDropDown.Items{1};
                app.EMGrightDropDown.Value = app.EMGrightDropDown.Items{2};
                writetable(app.channelDescriptions,channelDescriptionFilename);
            end

            count = 0;
            for k=1:size(app.trialsTable.Data,1)
                % don't include if there are no entries
                if app.trialsTable.Data.blockend(k) >= app.trialsTable.Data.blockstart(k)
                    count = count+1;
                    allMEPs{count}   = sprintf('All MEPs - block %d',k);
                    meanMEPs{count}   = sprintf('Mean rectified MEPs - block %d',k);
                    individualMEPs{count} = sprintf('Individual MEPs - block %d',k);
                end
            end
            quantity = [{'Whole Trial'} allMEPs meanMEPs individualMEPs {'PeakToPeak','Latency','Area','IO curve'}];
            app.quantitySelector.Items = quantity;
            updateGraphs(app);
        end

        function updateGraphs(app,f)
            if nargin<2 || isempty(f)
                f = app.Panel;
            end
            app.SelectlatencyonsetLButton.Visible = 'off';
            app.SelectendpointLButton.Visible = 'off';
            app.ResetlatencyendpointLButton.Visible = 'off';
            app.SelectlatencyonsetRButton.Visible = 'off';
            app.SelectendpointRButton.Visible = 'off';
            app.ResetlatencyendpointRButton.Visible = 'off';

            % calculate block level calculations
            [channel,channels] = getChannels(app);
            channels_to_plot = find([app.ShowEMGleftCheckBox.Value app.ShowEMGrightCheckBox.Value]);
            colors = colororder;
            
            quantity = app.quantitySelector.Value;
            s = subplot(1,1,1,'Parent',f);
            cla(s);
            ylabel(s,'mV');
            xlabel(s,''); 
            title(s,'');
            xlim(s,'auto');
            if strcmp(quantity,'Whole Trial')
                time = (1:size(app.data.alldata.data,2)) ./ app.data.samplerate * 1000;
                plot(s,time,app.data.alldata.data)
                xlabel(s,'time (ms)')
                ylabel(s,'mV');
                app.UseDataLCheckBox.Visible = 'off';
                app.UseDataRCheckBox.Visible = 'off';
                app.IsMEPCheckBox.Visible = 'off';
                app.BaselineendEditField.Visible = 'off';
                app.BaselineendEditFieldLabel.Visible = 'off';
                app.BaselinestartEditField.Visible = 'off';
                app.BaselinestartEditFieldLabel.Visible = 'off';
                app.lambdaEditField.Visible = 'off';
                app.lambdaLabel.Visible = 'off';
                app.MEPSelector.Visible = 'off';
                app.SingleMEPTable.Visible = 'off';
                app.TrialbasedmeasuresLabel.Visible = 'off';
                app.BlockMEPTable.Visible = 'off';
                app.BlockbasedmeasuresLabel.Visible = 'off';
                app.ZoominCheckBox.Visible = 'off';

            elseif strncmp(quantity,'All MEPs - block',8) || strncmp(quantity,'Mean rectified MEPs - block',19)
                if strncmp(quantity,'All MEPs - block',8)
                    blockNum = str2double(quantity(18:end));
                else
                    blockNum = str2double(quantity(29:end));
                end
                thisblock = app.data.block{blockNum};
                %size(thisblock)

                blockstart = app.trialsTable.Data.blockstart(blockNum);
                blockend = app.trialsTable.Data.blockend(blockNum);

                params = globalparameters;
                params.std = app.trialsTable.Data.n_std(blockNum);
                params.samplerate = app.data.samplerate;
                touseBlock = [app.toUseTable.toUse_1(blockstart:blockend) app.toUseTable.toUse_2(blockstart:blockend)];

                [blockResults,app.BlockMEPTable.Data,blockdata] = calculateMeasuresBlock(thisblock,params,touseBlock,channels);
                app.BlockMEPTable.Visible = 'on';
                app.BlockbasedmeasuresLabel.Visible = 'on';


                for k=blockstart:blockend
                    time = (1:size(app.data.e,2)) ./ app.data.samplerate * 1000;
                    if app.ShowEMGleftCheckBox.Value
                        if strncmp(quantity,'All MEPs - block',8)
                            plot(s,time-params.delsys_delay-params.trig_pos,squeeze(app.data.e(channels(1),:,k)));
                        end
                    end
                    if app.ShowEMGrightCheckBox.Value
                        if strncmp(quantity,'All MEPs - block',8)
                            plot(s,time-params.delsys_delay-params.trig_pos,squeeze(app.data.e(channels(2),:,k)));
                        end
                    end
                    hold(s,'on');
                end


                if strncmp(quantity,'Mean rectified MEPs - block',9)
                    for c=channels_to_plot
                        plot(s,time-params.delsys_delay-params.trig_pos,blockdata.block_mean_bc_abs(c,:));
                        hold (s,'on');
                        if ~isempty(blockResults.latency_regular_ms(c)) && ~isnan(blockResults.latency_regular_ms(c))
                            xline(s,blockResults.latency_regular_ms,'k');
                        end
                        if ~isempty(blockResults.endpoint_ms(c)) && ~isnan(blockResults.endpoint_ms(c))
                            xline(s,blockResults.endpoint_ms(c),'k--');
                        end
                    end

                end
                xlabel(s,'time (ms)')
                ylabel(s,'mV');

                app.UseDataLCheckBox.Visible = 'off';
                app.UseDataRCheckBox.Visible = 'off';
                app.IsMEPCheckBox.Visible = 'off';
                app.BaselineendEditField.Visible = 'off';
                app.BaselineendEditFieldLabel.Visible = 'off';
                app.BaselinestartEditField.Visible = 'off';
                app.BaselinestartEditFieldLabel.Visible = 'off';
                app.lambdaEditField.Visible = 'off';
                app.lambdaLabel.Visible = 'off';
                app.MEPSelector.Visible = 'off';
                app.SingleMEPTable.Visible = 'off';
                app.TrialbasedmeasuresLabel.Visible = 'off';
                app.ZoominCheckBox.Visible = 'off';

            elseif strncmp(quantity,'Individual MEPs',15) 
                app.ZoominCheckBox.Visible = 'on';

                MEPnumber = str2double(app.MEPSelector.Value(5:end));
                % Which block are we in?
                blockNum = str2double(quantity(25:end));
                thisblock = app.data.block{blockNum};
                thisdata = app.data.e(:,:,MEPnumber);
                time = (1:size(app.data.e,2)) ./ app.data.samplerate * 1000;
                touse = [app.toUseTable.toUse_1(MEPnumber) app.toUseTable.toUse_2(MEPnumber)];
                isMEP = app.toUseTable.isMEP(MEPnumber);

                blockstart = app.trialsTable.Data.blockstart(blockNum);
                blockend = app.trialsTable.Data.blockend(blockNum);
                touseBlock = [app.toUseTable.toUse_1(blockstart:blockend) app.toUseTable.toUse_2(blockstart:blockend)];

                params = globalparameters;
                params.std = app.trialsTable.Data.n_std(blockNum);
                params.samplerate = app.data.samplerate;

                app.lambdaEditField.Value = app.toUseTable.lambda(MEPnumber);

                c=1;
                if app.ShowEMGleftCheckBox.Value
                    if touse(1)
                        app.UseDataLCheckBox.Value = true;
                        plot(s,time-params.delsys_delay-params.trig_pos,thisdata(channels(c),:),'Color',colors(c,:));
                        hold(s,'on');
                    else
                        app.UseDataLCheckBox.Value = false;
                        plot(s,time-params.delsys_delay-params.trig_pos,thisdata(channels(c),:),'Color',[0.5 0.5 0.5]);
                    end
                end
                c=2;
                if app.ShowEMGrightCheckBox.Value
                    if touse(2)
                        app.UseDataRCheckBox.Value = true;
                        plot(s,time-params.delsys_delay-params.trig_pos,thisdata(channels(c),:),'Color',colors(c,:));
                    else
                        app.UseDataRCheckBox.Value = false;
                        plot(s,time-params.delsys_delay-params.trig_pos,thisdata(channels(c),:),'Color',[0.5 0.5 0.5]);
                    end
                end
                app.IsMEPCheckBox.Value = isMEP;              

                app.BaselinestartEditField.Value = app.toUseTable.baselineStart(MEPnumber);
                app.BaselineendEditField.Value =  app.toUseTable.baselineEnd(MEPnumber);
                app.lambdaEditField.Value = app.toUseTable.lambda(MEPnumber);

                % Plot the baseline
                bl_start = app.BaselinestartEditField.Value;
                bl_end = app.BaselineendEditField.Value;
                hold(s,'on');
                yy = 0.05;
                fill(s,[bl_start bl_end bl_end bl_start],[-yy -yy yy yy],[0.5 0.5 0.5],'FaceAlpha',0.2,'EdgeAlpha',0.2);

                xlabel(s,'time (ms)')
                ylabel(s,'mV');

                app.UseDataLCheckBox.Visible = 'on';
                app.UseDataRCheckBox.Visible = 'on';
                app.IsMEPCheckBox.Visible = 'on';
                app.BaselineendEditField.Visible = 'on';
                app.BaselineendEditFieldLabel.Visible = 'on';
                app.BaselinestartEditField.Visible = 'on';
                app.BaselinestartEditFieldLabel.Visible = 'on';

                if app.ShowEMGleftCheckBox.Value
                    app.SelectlatencyonsetLButton.Visible = 'on';
                    app.SelectendpointLButton.Visible = 'on';
                    app.ResetlatencyendpointLButton.Visible = 'on';
                end
                if app.ShowEMGrightCheckBox.Value
                    app.SelectlatencyonsetRButton.Visible = 'on';
                    app.SelectendpointRButton.Visible = 'on';
                    app.ResetlatencyendpointRButton.Visible = 'on';
                end

                app.lambdaEditField.Visible = 'on';
                app.lambdaLabel.Visible = 'on';
                app.MEPSelector.Visible = 'on';

                % Trial level calculations               
                latency_ms = [app.toUseTable.latencyOnset_1(MEPnumber) app.toUseTable.latencyOnset_2(MEPnumber)];
                endpoint_ms = [app.toUseTable.endpoint_1(MEPnumber) app.toUseTable.endpoint_2(MEPnumber)]; 
                baseline_start = app.toUseTable.baselineStart(MEPnumber);
                baseline_end = app.toUseTable.baselineEnd(MEPnumber);
                alldata = app.data.alldata.data;
                isMEP = app.toUseTable.isMEP(MEPnumber);
                lambda = app.toUseTable.lambda(MEPnumber);
                [trialResults,app.SingleMEPTable.Data] = calculateMeasuresTrial(thisdata,alldata,thisblock,params,touse,channels,isMEP,...
                    latency_ms,endpoint_ms,baseline_start,baseline_end);
                app.SingleMEPTable.Visible = 'on';
                app.TrialbasedmeasuresLabel.Visible = 'on';

                proportionNoisyIndex1 = find(strcmp(app.SingleMEPTable.Data.Measure,'proportionNoisy1'));
                proportionNoisy1 = app.SingleMEPTable.Data.Value(proportionNoisyIndex1);
                proportionNoisyIndex2 = find(strcmp(app.SingleMEPTable.Data.Measure,'proportionNoisy2'));
                proportionNoisy2 = app.SingleMEPTable.Data.Value(proportionNoisyIndex2);

                relativeMaxPeakToPeakBaselineIndex1 = find(strcmp(app.SingleMEPTable.Data.Measure,'relativeMaxPeakToPeakBaseline1'));
                relativeMaxPeakToPeakBaseline1 = app.SingleMEPTable.Data.Value(relativeMaxPeakToPeakBaselineIndex1);
                relativeMaxPeakToPeakBaselineIndex2 = find(strcmp(app.SingleMEPTable.Data.Measure,'relativeMaxPeakToPeakBaseline2'));
                relativeMaxPeakToPeakBaseline2 = app.SingleMEPTable.Data.Value(relativeMaxPeakToPeakBaselineIndex2);
             
                bgColorRed = uistyle("BackgroundColor",[1 0.6 0.6]);
                bgColorWhite = uistyle("BackgroundColor",[1 1 1]);

                if proportionNoisy1 >= 0.5
                    addStyle(app.SingleMEPTable,bgColorRed,"cell",[proportionNoisyIndex1,2]);
                else
                    addStyle(app.SingleMEPTable,bgColorWhite,"cell",[proportionNoisyIndex1,2]);
                end

                if proportionNoisy2 >= 0.5
                    addStyle(app.SingleMEPTable,bgColorRed,"cell",[proportionNoisyIndex2,2]);
                else
                    addStyle(app.SingleMEPTable,bgColorWhite,"cell",[proportionNoisyIndex2,2]);
                end

                if relativeMaxPeakToPeakBaseline1 > lambda
                    addStyle(app.SingleMEPTable,bgColorRed,"cell",[relativeMaxPeakToPeakBaselineIndex1,2]);
                else
                    addStyle(app.SingleMEPTable,bgColorWhite,"cell",[relativeMaxPeakToPeakBaselineIndex1,2]);
                end

                if relativeMaxPeakToPeakBaseline2 > lambda
                    addStyle(app.SingleMEPTable,bgColorRed,"cell",[relativeMaxPeakToPeakBaselineIndex2,2]);
                else
                    addStyle(app.SingleMEPTable,bgColorWhite,"cell",[relativeMaxPeakToPeakBaselineIndex2,2]);
                end

                colors = colororder;
                for c=channels_to_plot
                    if ~isempty(trialResults.latency_regular_ms(c)) && ~isnan(trialResults.latency_regular_ms(c))
                        xline(s,trialResults.latency_regular_ms(c),'-','Color',colors(c,:));
                    end

                    if ~isempty(trialResults.latencyJose_ms(c)) && ~isnan(trialResults.latencyJose_ms(c))
                        xline(s,trialResults.latencyJose_ms(c),':','Color',colors(c,:));
                    end

                    if ~isempty(trialResults.latency_semi_manual_ms(c)) && ~isnan(trialResults.latency_semi_manual_ms(c))
                        xline(s,trialResults.latency_semi_manual_ms(c),'-','Color',colors(c,:),'LineWidth',2);
                    end


                    if ~isempty(trialResults.endpoint_ms(c)) && ~isnan(trialResults.endpoint_ms(c))
                        xline(s,trialResults.endpoint_ms(c),'--','Color',colors(c,:));
                    end

                    xlim(s,'auto');
                    if touse(1) && app.ZoominCheckBox.Value && ...
                            ~isempty(trialResults.latency_regular_ms(c)) && ~isnan(trialResults.latency_regular_ms(c)) && ...
                            ~isempty(trialResults.endpoint_ms(c)) && ~isnan(trialResults.endpoint_ms(c))
                        xlim(s,[trialResults.latency_regular_ms(c)-100 trialResults.endpoint_ms(c)+100]);
                    end
                    if touse(2) && app.ZoominCheckBox.Value && ...
                            ~isempty(trialResults.latency_regular_ms(c)) && ~isnan(trialResults.latency_regular_ms(c)) && ...
                            ~isempty(trialResults.endpoint_ms(c)) && ~isnan(trialResults.endpoint_ms(c))
                        xlim(s,[trialResults.latency_regular_ms(c)-100 trialResults.endpoint_ms(c)+100]);
                    end

                end

                % calculate block level calculations
                [~,app.BlockMEPTable.Data] = calculateMeasuresBlock(thisblock,params,touseBlock,channels);

                app.BlockMEPTable.Visible = 'on';
                app.BlockbasedmeasuresLabel.Visible = 'on';
            elseif any(strcmp(quantity,{'PeakToPeak','Latency','Area'}))
                numBlocks = numel(app.trialsTable.Data.blockstart);
                params = globalparameters;
                params.samplerate = app.data.samplerate;
                alldata = app.data.alldata.data;

                for blockNum=1:numBlocks
                    params.std = app.trialsTable.Data.n_std(blockNum);

                    blockstart = app.trialsTable.Data.blockstart(blockNum);
                    blockend = app.trialsTable.Data.blockend(blockNum);
                    for MEPnumber=blockstart:blockend
                        thisdata = app.data.e(:,:,MEPnumber);
                        thisblock = app.data.block{blockNum};
                        time = (1:size(app.data.e,1)) ./ app.data.samplerate * 1000;
                        touse = [app.toUseTable.toUse_1(MEPnumber) app.toUseTable.toUse_2(MEPnumber)];
                        isMEP = app.toUseTable.isMEP(MEPnumber);
                        latency_ms = [app.toUseTable.latencyOnset_1(MEPnumber) app.toUseTable.latencyOnset_1(MEPnumber)];
                        endpoint_ms = [app.toUseTable.endpoint_1(MEPnumber) app.toUseTable.endpoint_1(MEPnumber)];
                        baseline_start = app.toUseTable.baselineStart(MEPnumber);
                        baseline_end = app.toUseTable.baselineEnd(MEPnumber);
                        %lambda = app.toUseTable.lambda(MEPnumber);
                        trialResults(MEPnumber) = calculateMeasuresTrial(thisdata,alldata,thisblock,params,touse,channels,isMEP,...
                            latency_ms,endpoint_ms,baseline_start,baseline_end);
                    end
                end
                for blockNum=1:numBlocks
                    colors = colororder;
                    blockstart = app.trialsTable.Data.blockstart(blockNum);
                    blockend = app.trialsTable.Data.blockend(blockNum);
                    
                    for MEPnumber=blockstart:blockend
                        if strcmp(quantity,'PeakToPeak')
                            plot(s,MEPnumber,trialResults(MEPnumber).PeakToPeak(1),'*','Color',colors(blockNum,:));
                            hold(s,'on');
                            plot(s,MEPnumber,trialResults(MEPnumber).PeakToPeak(2),'o','Color',colors(blockNum,:));
                            ylabel(s,'Peak to peak (mV)');
                        elseif strcmp(quantity,'Latency')
                            plot(s,MEPnumber,trialResults(MEPnumber).latency_regular_ms(1),'*','Color',colors(blockNum,:));
                            hold(s,'on');
                            plot(s,MEPnumber,trialResults(MEPnumber).latency_regular_ms(2),'o','Color',colors(blockNum,:));
                            ylabel(s,'Latency (ms)');
                        elseif strcmp(quantity,'Area')
                            plot(s,MEPnumber,trialResults(MEPnumber).area(1),'*','Color',colors(blockNum,:));
                            hold(s,'on');
                            plot(s,MEPnumber,trialResults(MEPnumber).area(2),'o','Color',colors(blockNum,:));
                            ylabel(s,'Area');
                        end
                    end
                end
            elseif strcmp(quantity,'IO curve')
                app.ZoominCheckBox.Visible = 'off';
                app.IOcurveOKCheckBox.Visible = 'on';
                app.IOcurve_params = get_IOcurve_params(app.subject, app.repetition);
                app.IOcurveOKCheckBox.Value = app.IOcurve_params.IOcurve_OK;

                [result,percent_threshold,logMEPsMEP0,r2] = ...
                    fitIOcurveRepetition(app.subjectSelector.Value,...
                    app.repetitionSelector.Value,app.channelDescriptions.channels);
                x = percent_threshold;
                y = logMEPsMEP0;
                hold(s,'off');
                minx = 0; %min(x);
                maxx = 200; %max(x);
                xs = linspace(minx,maxx,100);
                yfit = evaluateIOfunction(xs,result(1),result(2),result(3));
                plot(s,xs,yfit);
                hold(s,'on');
                scatter(s,x,y,40,'filled');
                xlabel(s,'% motor threshold');
                ylabel(s,'log10 PeakToPeak (log10 mV)');
                y = evaluateIOfunction(percent_threshold,result(1),result(2),result(3));
                cost = sum((logMEPsMEP0 - y).^2);
                title(s,sprintf('Δy = %.4f, s = %.4f, m = %.4f, cost = %.4f R^2 = %.2f',...
                    result(1),result(2),result(3),cost, r2));
            else
                error(['Unknown quantity: ' quantity]);
            end

        end

        function ResetlatencyendpointButtonPushed(app,ind)
            MEPnumber = str2double(app.MEPSelector.Value(5:end));
            app.toUseTable.(['latencyOnset_' num2str(ind)])(MEPnumber) = -1;
            app.toUseTable.(['endpoint_' num2str(ind)])(MEPnumber) = -1;
            writetable(app.toUseTable,getToUseLocation(app));
            updateGraphs(app);
        end

        function SelectlatencyonsetButtonPushed(app,ind)
            ax = subplot(1,1,1,'Parent',app.Panel);
            roi = drawpoint(ax,'Visible','off');
            x = roi.Position(1);
            MEPnumber = str2double(app.MEPSelector.Value(5:end));
            app.toUseTable.(['latencyOnset_' num2str(ind)])(MEPnumber) = x;
            writetable(app.toUseTable,getToUseLocation(app));
            updateGraphs(app);
        end

        function SelectendpointButtonPushed(app,ind)
            ax = subplot(1,1,1,'Parent',app.Panel);
            roi = drawpoint(ax,'Visible','off');
            x = roi.Position(1);
            MEPnumber = str2double(app.MEPSelector.Value(5:end));
            app.toUseTable.(['endpoint_' num2str(ind)])(MEPnumber) = x;
            writetable(app.toUseTable,getToUseLocation(app));
            updateGraphs(app);
        end

        function UseDataCheckBoxValueChanged(app,ind,value)
            MEPnumber = str2double(app.MEPSelector.Value(5:end));
            app.toUseTable.(['toUse_' num2str(ind)])(MEPnumber) = value;
            writetable(app.toUseTable,getToUseLocation(app));
            updateGraphs(app);
        end


    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            d = dir('results/*/*.mat');
            if numel(d)==0
                errordlg('There needs to be a directory ''results'' containing folders with .mat files');
                delete(app)
                return
            end
            
            dirnames = getSubjects;
            
            set(app.subjectSelector,'Items',dirnames);
            updateSubject(app);
        end

        % Cell edit callback: trialsTable
        function trialsTableCellEdit(app, event)
            % Save the table
            t = app.trialsTable.Data;
            writetable(t,['results/' app.subject '/' app.repetition '.csv']);
            updateSubject(app);
        end

        % Value changed function: subjectSelector
        function subjectSelectorValueChanged(app, event)
            updateSubject(app);            
        end

        % Value changed function: quantitySelector
        function quantitySelectorValueChanged(app, event)
            quantity = app.quantitySelector.Value;
            if strncmp(quantity,'Individual',10)
                blockNum = str2double(quantity(25:end));
                blockstart = app.trialsTable.Data.blockstart(blockNum);
                blockend = app.trialsTable.Data.blockend(blockNum);
                for k=blockstart:blockend
                    v{k-blockstart+1} = sprintf('MEP %d',k);
                end
                app.MEPSelector.Items = v;

                app.MEPSelector.Visible = 'on';
                app.UseDataLCheckBox.Visible = 'on';
                app.UseDataRCheckBox.Visible = 'on';
                app.IsMEPCheckBox.Visible = 'on';
                app.BaselineendEditField.Visible = 'on';
                app.BaselineendEditFieldLabel.Visible = 'on';
                app.BaselinestartEditField.Visible = 'on';
                app.BaselinestartEditFieldLabel.Visible = 'on';
                
                if app.ShowEMGleftCheckBox.Value
                    app.SelectlatencyonsetLButton.Visible = 'on';
                    app.SelectendpointLButton.Visible = 'on';
                    app.ResetlatencyendpointLButton.Visible = 'on';
                end
                if app.ShowEMGrightCheckBox.Value
                    app.SelectlatencyonsetRButton.Visible = 'on';
                    app.SelectendpointRButton.Visible = 'on';
                    app.ResetlatencyendpointRButton.Visible = 'on';
                end

                app.lambdaEditField.Visible = 'on';
                app.lambdaLabel.Visible = 'on';
            else
                app.MEPSelector.Visible = 'off';
                app.UseDataLCheckBox.Visible = 'off';
                app.UseDataRCheckBox.Visible = 'off';
                app.IsMEPCheckBox.Visible = 'off';
                app.BaselineendEditField.Visible = 'off';
                app.BaselineendEditFieldLabel.Visible = 'off';
                app.BaselinestartEditField.Visible = 'off';
                app.BaselinestartEditFieldLabel.Visible = 'off';

                app.SelectlatencyonsetLButton.Visible = 'off';
                app.SelectendpointLButton.Visible = 'off';
                app.ResetlatencyendpointLButton.Visible = 'off';

                app.SelectlatencyonsetRButton.Visible = 'off';
                app.SelectendpointRButton.Visible = 'off';
                app.ResetlatencyendpointRButton.Visible = 'off';

                app.lambdaEditField.Visible = 'off';
                app.lambdaLabel.Visible = 'off';
            end
            updateGraphs(app);
        end

        % Value changed function: MEPSelector
        function MEPSelectorValueChanged(app, event)
            updateGraphs(app);            
        end

        % Callback function
        function channelSelectorValueChanged(app, event)
            updateGraphs(app);
        end

        % Value changed function: UseDataLCheckBox
        function UseDataLCheckBoxValueChanged(app, event)
            UseDataCheckBoxValueChanged(app,1,app.UseDataLCheckBox.Value);
        end

        % Button pushed function: ExportsubjectButton
        function ExportsubjectButtonPushed(app, event)
            app.ExportsubjectButton.Enable = 'off';
            app.ExportsubjectButton.Text = 'calculating . . .';
            pause(1);
            [~, channels] = getChannels(app);
            exportToCSV(app.subjectSelector.Value,channels);
            app.ExportsubjectButton.Enable = 'on';
            app.ExportsubjectButton.Text = 'Export subject';
        end

        % Value changed function: ZoominCheckBox
        function ZoominCheckBoxValueChanged(app, event)
            updateGraphs(app);
        end

        % Value changed function: repetitionSelector
        function repetitionSelectorValueChanged(app, event)
            value = app.repetitionSelector.Value;
            updateRepetition(app);
        end

        % Button pushed function: ExportallsubjectsButton
        function ExportallsubjectsButtonPushed(app, event)
            app.ExportallsubjectsButton.Enable = 'off';
            app.ExportallsubjectsButton.Text = 'calculating . . .';
            pause(1);
            exportToCSV;
            app.ExportallsubjectsButton.Enable = 'on';
            app.ExportallsubjectsButton.Text = 'Export all subjects';

        end

        % Button pushed function: OpeninnewwindowButton
        function OpeninnewwindowButtonPushed(app, event)
            f = figure;
            updateGraphs(app,f);
        end

        % Button pushed function: SelectlatencyonsetLButton
        function SelectlatencyonsetLButtonPushed(app, event)
            SelectlatencyonsetButtonPushed(app,1);
        end

        % Callback function
        function PanelButtonDown(app, event)

        end

        % Button pushed function: SelectendpointLButton
        function SelectendpointLButtonPushed(app, event)
            SelectendpointButtonPushed(app,1);
        end

        % Button pushed function: ResetlatencyendpointLButton
        function ResetlatencyendpointLButtonPushed(app, event)
            ResetlatencyendpointButtonPushed(app,1);
        end

        % Value changed function: BaselineendEditField
        function BaselineendEditFieldValueChanged(app, event)
            MEPnumber = str2double(app.MEPSelector.Value(5:end));
            app.toUseTable.baselineEnd(MEPnumber) = app.BaselineendEditField.Value;
            writetable(app.toUseTable,getToUseLocation(app));

            updateGraphs(app);
        end

        % Value changed function: BaselinestartEditField
        function BaselinestartEditFieldValueChanged(app, event)
            MEPnumber = str2double(app.MEPSelector.Value(5:end));
            app.toUseTable.baselineStart(MEPnumber) = app.BaselinestartEditField.Value;
            writetable(app.toUseTable,getToUseLocation(app));

            updateGraphs(app);
        end

        % Value changed function: lambdaEditField
        function lambdaEditFieldValueChanged(app, event)
            MEPnumber = str2double(app.MEPSelector.Value(5:end));
            app.toUseTable.lambda(MEPnumber) = app.lambdaEditField.Value;
            writetable(app.toUseTable,getToUseLocation(app));

            updateGraphs(app);
        end

        % Value changed function: IsMEPCheckBox
        function IsMEPCheckBoxValueChanged(app, event)
            value = app.IsMEPCheckBox.Value;
            MEPnumber = str2double(app.MEPSelector.Value(5:end));
            app.toUseTable.isMEP(MEPnumber) = value;
            writetable(app.toUseTable,getToUseLocation(app));
            updateGraphs(app);
        end

        % Value changed function: IOcurveOKCheckBox
        function IOcurveOKCheckBoxValueChanged(app, event)
            value = app.IOcurveOKCheckBox.Value;  
            app.IOcurve_params.IOcurve_OK = value;
            fn = getIOCurve_paramLocation(app);
            writetable(app.IOcurve_params,fn);            
        end

        % Value changed function: EMGleftDropDown, EMGrightDropDown
        function EMGrightDropDownValueChanged(app, event)
            % Save the values to a file
            location = app.getChannelDescriptionLocation;
            t = app.channelDescriptions;
            t.names{1} = app.EMGleftDropDown.Value;
            t.names{2} = app.EMGrightDropDown.Value;
            writetable(t,location);
            updateGraphs(app);
        end

        % Value changed function: channelSelector
        function channelSelectorValueChanged2(app, event)
            updateGraphs(app);
        end

        % Value changed function: ShowEMGleftCheckBox, ShowEMGrightCheckBox
        function ShowEMGleftCheckBoxValueChanged(app, event)
            updateGraphs(app);
        end

        % Button pushed function: SelectlatencyonsetRButton
        function SelectlatencyonsetRButtonPushed(app, event)
            SelectlatencyonsetButtonPushed(app,2);
        end

        % Button pushed function: ResetlatencyendpointRButton
        function ResetlatencyendpointRButtonPushed(app, event)
            ResetlatencyendpointButtonPushed(app,2);
        end

        % Button pushed function: SelectendpointRButton
        function SelectendpointRButtonPushed(app, event)
            SelectendpointButtonPushed(app,2);
        end

        % Value changed function: UseDataRCheckBox
        function UseDataRCheckBoxValueChanged(app, event)
            UseDataCheckBoxValueChanged(app,2,app.UseDataRCheckBox.Value);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.subjectSelector = uidropdown(app.UIFigure);
            app.subjectSelector.ValueChangedFcn = createCallbackFcn(app, @subjectSelectorValueChanged, true);
            app.quantitySelector = uidropdown(app.UIFigure);
            app.quantitySelector.Items = {'Whole Trial', 'All MEPs', 'Individual MEPs'};
            app.quantitySelector.ValueChangedFcn = createCallbackFcn(app, @quantitySelectorValueChanged, true);
            app.quantitySelector.Value = 'Whole Trial';
            app.Panel = uipanel(app.UIFigure);
            app.MEPSelector = uidropdown(app.UIFigure);
            app.MEPSelector.Items = {'MEP 1'};
            app.MEPSelector.ValueChangedFcn = createCallbackFcn(app, @MEPSelectorValueChanged, true);
            app.MEPSelector.Value = 'MEP 1';
            app.trialsTable = uitable(app.UIFigure);
            app.trialsTable.ColumnName = {'Num'; 'Description'; '% motor threshold'; 'machine setting'; 'Block start'; 'Block end'; '# std'};
            app.trialsTable.RowName = {};
            app.trialsTable.ColumnEditable = [false true true true true true true];
            app.trialsTable.CellEditCallback = createCallbackFcn(app, @trialsTableCellEdit, true);
            app.UseDataLCheckBox = uicheckbox(app.UIFigure);
            app.UseDataLCheckBox.ValueChangedFcn = createCallbackFcn(app, @UseDataLCheckBoxValueChanged, true);
            app.UseDataLCheckBox.Text = 'Use Data L';
            app.SingleMEPTable = uitable(app.UIFigure);
            app.SingleMEPTable.ColumnName = {'Measure'; 'Value'};
            app.SingleMEPTable.RowName = {};
            app.ExportsubjectButton = uibutton(app.UIFigure, 'push');
            app.ExportsubjectButton.ButtonPushedFcn = createCallbackFcn(app, @ExportsubjectButtonPushed, true);
            app.ExportsubjectButton.Text = 'Export subject';
            app.BlockMEPTable = uitable(app.UIFigure);
            app.BlockMEPTable.ColumnName = {'Measure'; 'Value'};
            app.BlockMEPTable.RowName = {};
            app.TrialbasedmeasuresLabel = uilabel(app.UIFigure);
            app.TrialbasedmeasuresLabel.Text = 'Trial-based measures';
            app.BlockbasedmeasuresLabel = uilabel(app.UIFigure);
            app.BlockbasedmeasuresLabel.Text = 'Block-based measures';
            app.ZoominCheckBox = uicheckbox(app.UIFigure);
            app.ZoominCheckBox.ValueChangedFcn = createCallbackFcn(app, @ZoominCheckBoxValueChanged, true);
            app.ZoominCheckBox.Text = 'Zoom in';
            app.repetitionSelector = uidropdown(app.UIFigure);
            app.repetitionSelector.ValueChangedFcn = createCallbackFcn(app, @repetitionSelectorValueChanged, true);
            app.ExportallsubjectsButton = uibutton(app.UIFigure, 'push');
            app.ExportallsubjectsButton.ButtonPushedFcn = createCallbackFcn(app, @ExportallsubjectsButtonPushed, true);
            app.ExportallsubjectsButton.Text = 'Export all subjects';
            app.OpeninnewwindowButton = uibutton(app.UIFigure, 'push');
            app.OpeninnewwindowButton.ButtonPushedFcn = createCallbackFcn(app, @OpeninnewwindowButtonPushed, true);
            app.OpeninnewwindowButton.Text = 'Open in new window';
            app.SelectlatencyonsetLButton = uibutton(app.UIFigure, 'push');
            app.SelectlatencyonsetLButton.ButtonPushedFcn = createCallbackFcn(app, @SelectlatencyonsetLButtonPushed, true);
            app.SelectlatencyonsetLButton.Visible = 'off';
            app.SelectlatencyonsetLButton.Text = 'Select latency onset L';
            app.SelectendpointLButton = uibutton(app.UIFigure, 'push');
            app.SelectendpointLButton.ButtonPushedFcn = createCallbackFcn(app, @SelectendpointLButtonPushed, true);
            app.SelectendpointLButton.Visible = 'off';
            app.SelectendpointLButton.Text = 'Select endpoint L';
            app.BaselinestartEditFieldLabel = uilabel(app.UIFigure);
            app.BaselinestartEditFieldLabel.Text = 'Baseline start';
            app.BaselinestartEditField = uieditfield(app.UIFigure, 'numeric');
            app.BaselinestartEditField.ValueChangedFcn = createCallbackFcn(app, @BaselinestartEditFieldValueChanged, true);
            app.BaselinestartEditField.Visible = 'off';
            app.BaselinestartEditField.Value = -100;
            app.BaselineendEditFieldLabel = uilabel(app.UIFigure);
            app.BaselineendEditFieldLabel.Text = 'Baseline end';
            app.BaselineendEditField = uieditfield(app.UIFigure, 'numeric');
            app.BaselineendEditField.ValueChangedFcn = createCallbackFcn(app, @BaselineendEditFieldValueChanged, true);
            app.BaselineendEditField.Visible = 'off';
            app.lambdaLabel = uilabel(app.UIFigure);
            app.lambdaLabel.Visible = 'off';
            app.lambdaLabel.Text = 'λ';
            app.lambdaEditField = uieditfield(app.UIFigure, 'numeric');
            app.lambdaEditField.ValueChangedFcn = createCallbackFcn(app, @lambdaEditFieldValueChanged, true);
            app.lambdaEditField.Visible = 'off';
            app.lambdaEditField.Value = 3;
            app.ResetlatencyendpointLButton = uibutton(app.UIFigure, 'push');
            app.ResetlatencyendpointLButton.ButtonPushedFcn = createCallbackFcn(app, @ResetlatencyendpointLButtonPushed, true);
            app.ResetlatencyendpointLButton.Visible = 'off';
            app.ResetlatencyendpointLButton.Text = 'Reset latency / endpoint L';
            app.IsMEPCheckBox = uicheckbox(app.UIFigure);
            app.IsMEPCheckBox.ValueChangedFcn = createCallbackFcn(app, @IsMEPCheckBoxValueChanged, true);
            app.IsMEPCheckBox.Text = 'Is MEP?';
            app.IOcurveOKCheckBox = uicheckbox(app.UIFigure);
            app.IOcurveOKCheckBox.ValueChangedFcn = createCallbackFcn(app, @IOcurveOKCheckBoxValueChanged, true);
            app.IOcurveOKCheckBox.Visible = 'off';
            app.IOcurveOKCheckBox.Text = 'IOcurve OK';
            app.IOcurveOKCheckBox.Value = true;
            app.EMGleftDropDownLabel = uilabel(app.UIFigure);
            app.EMGleftDropDownLabel.Text = 'EMG left';
            app.EMGleftDropDown = uidropdown(app.UIFigure);
            app.EMGleftDropDown.ValueChangedFcn = createCallbackFcn(app, @EMGrightDropDownValueChanged, true);
            app.EMGrightDropDownLabel = uilabel(app.UIFigure);
            app.EMGrightDropDownLabel.Text = 'EMG right';
            app.EMGrightDropDown = uidropdown(app.UIFigure);
            app.EMGrightDropDown.ValueChangedFcn = createCallbackFcn(app, @EMGrightDropDownValueChanged, true);
            app.channelSelector = uidropdown(app.UIFigure);
            app.channelSelector.Items = {'EMG left', 'EMG right'};
            app.channelSelector.ValueChangedFcn = createCallbackFcn(app, @channelSelectorValueChanged2, true);
            app.channelSelector.Value = 'EMG left';
            app.ShowEMGleftCheckBox = uicheckbox(app.UIFigure);
            app.ShowEMGleftCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowEMGleftCheckBoxValueChanged, true);
            app.ShowEMGleftCheckBox.Text = 'Show EMG left';
            app.ShowEMGleftCheckBox.Value = true;
            app.ShowEMGrightCheckBox = uicheckbox(app.UIFigure);
            app.ShowEMGrightCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowEMGleftCheckBoxValueChanged, true);
            app.ShowEMGrightCheckBox.Text = 'Show EMG right';
            app.ShowEMGrightCheckBox.Value = true;
            app.ResetlatencyendpointRButton = uibutton(app.UIFigure, 'push');
            app.ResetlatencyendpointRButton.ButtonPushedFcn = createCallbackFcn(app, @ResetlatencyendpointRButtonPushed, true);
            app.ResetlatencyendpointRButton.Visible = 'off';
            app.ResetlatencyendpointRButton.Text = 'Reset latency / endpoint R';
            app.SelectlatencyonsetRButton = uibutton(app.UIFigure, 'push');
            app.SelectlatencyonsetRButton.ButtonPushedFcn = createCallbackFcn(app, @SelectlatencyonsetRButtonPushed, true);
            app.SelectlatencyonsetRButton.Visible = 'off';
            app.SelectlatencyonsetRButton.Text = 'Select latency onset R';
            app.SelectendpointRButton = uibutton(app.UIFigure, 'push');
            app.SelectendpointRButton.ButtonPushedFcn = createCallbackFcn(app, @SelectendpointRButtonPushed, true);
            app.SelectendpointRButton.Visible = 'off';
            app.SelectendpointRButton.Text = 'Select endpoint R';
            app.UseDataRCheckBox = uicheckbox(app.UIFigure);
            app.UseDataRCheckBox.ValueChangedFcn = createCallbackFcn(app, @UseDataRCheckBoxValueChanged, true);
            app.UseDataRCheckBox.Text = 'Use Data R';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MEP_viewer2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end